classdef AINetProcessor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Menu                        matlab.ui.container.Menu
        OpenFolderMenu              matlab.ui.container.Menu
        UITable                     matlab.ui.control.Table
        FolderEditFieldLabel        matlab.ui.control.Label
        FolderEditField             matlab.ui.control.EditField
        BrowseButton                matlab.ui.control.Button
        NeuralNetDropDownLabel      matlab.ui.control.Label
        NeuralNetDropDown           matlab.ui.control.DropDown
        AnalyzeFolderButton         matlab.ui.control.Button
        SaveResultsButton           matlab.ui.control.Button
        SaveResultImageCheckBox     matlab.ui.control.CheckBox
        ThresholdsListBoxLabel      matlab.ui.control.Label
        ThresholdsListBox           matlab.ui.control.ListBox
        AddThresholdEditFieldLabel  matlab.ui.control.Label
        AddThresholdEditField       matlab.ui.control.NumericEditField
        MultiplethresholdscanbeselectedbyholdingCTRLwhenclickingLabel  matlab.ui.control.Label
        ThresholdsareonlyappliedwhensavingtheSummaryxlsxfileLabel  matlab.ui.control.Label
    end

    properties (Access = private)
        Folder% Description
        net % Description
        filesList % Description
        AIName
        AIFiles
        sortData % Description
    end
    
    methods (Access = private)
        
        function OpenFolder(app)
            files = struct2table(dir(fullfile(app.Folder)));
            
            files.name = string(files.name);
            files = files(~files.isdir,:);
            files = files(contains(files.name,'.tif','IgnoreCase',true),:);
            excelFile =  struct2table(dir(fullfile(app.Folder,'*.xlsx')));
            if ~isempty(excelFile)
                excelFile = excelFile(~contains(excelFile.name,'$'),:); % removes Auto generated backups from havig the file open in excel
            end
            if ~isempty(excelFile) && any(contains(excelFile.name,'Summary')) % The folder was sorted to create training data
                
                excelFile = excelFile(contains(excelFile.name,'Summary'),:);
                [status,sheets] = xlsfinfo(fullfile(app.Folder,excelFile.name{1}));
                sheets = sheets(~contains(sheets,'Sheet'));
                message{1}= sprintf('The Following networks have been tested on this folder:');
                for i = 1:numel(sheets)
                    message{i+1} = sheets{i};
                end
                uialert(app.UIFigure,message,'Images already analized.','Icon','Info');
                if numel(sheets)==1
                    tempTable = readtable(fullfile(app.Folder,excelFile.name),'sheet',sheets{1});
                    app.UITable.Data=tempTable;
                end
                app.filesList = files.name;
                app.UITable.Data=table(files.name,zeros(height(files),1),zeros(height(files),1),NaT(height(files),1),NaT(height(files),1),repmat(duration(0,0,0),height(files),1),'VariableNames',regexprep(app.UITable.ColumnName,{' ','%'},''));
            else
                app.UITable.Data=table(files.name,zeros(height(files),1),zeros(height(files),1),NaT(height(files),1),NaT(height(files),1),repmat(duration(0,0,0),height(files),1),'VariableNames',regexprep(app.UITable.ColumnName,{' ','%'},''));
            end
        end
        
        function enableFollowUp(app)
            if isfolder(app.FolderEditField.Value)
                app.AnalyzeFolderButton.Enable = 'on';
                app.SaveResultsButton.Enable = 'on';
            else
                app.AnalyzeFolderButton.Enable = 'off';
                app.SaveResultsButton.Enable = 'off';
            end
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AIName = app.NeuralNetDropDown.Items;
            app.AIFiles = {'CNN_VitisNet.mat'};
            load(app.AIFiles{1},'net2');
            app.net = net2;

        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.Folder = uigetdir(fullfile(getuserdir,'..'),'Select the folder containing your data');
            if app.Folder == 0
                return
            end
            app.FolderEditField.Value = app.Folder;
            OpenFolder(app);
            enableFollowUp(app);
        end

        % Value changed function: NeuralNetDropDown
        function NeuralNetDropDownValueChanged(app, event)
            try
            value = app.NeuralNetDropDown.Value;
            if strcmpi(value,'Browse...')
                [file,path] = uigetfile('*.mat','Select a Network file.');
                if file == 0
                    app.NeuralNetDropDown.Value = event.PreviousValue;
                    return
                end
                netStruct = load(fullfile(path,file));
                names = fieldnames(netStruct);
                for i = 1:numel(names)
                    switch class(netStruct.(names{i}))
                        case 'SeriesNetwork'
                            app.net = netStruct.(names{i});
                            app.AIFiles = [app.AIFiles,{fullfile(path,file)}];
                            app.AIName = [app.AIName,{file}];
                            app.NeuralNetDropDown.Items = [app.NeuralNetDropDown.Items(1:end-1),{file},app.NeuralNetDropDown.Items(end)];
                            app.NeuralNetDropDown.Value = file;
                            break
                        case 'DAGNetwork'
                            app.net = netStruct.(names{i});
                            app.AIFiles = [app.AIFiles,{fullfile(path,file)}];
                            app.AIName = [app.AIName,{file}];
                            app.NeuralNetDropDown.Items = [app.NeuralNetDropDown.Items(1:end-1),{file},app.NeuralNetDropDown.Items(end)];
                            app.NeuralNetDropDown.Value = file;
                            break
                        otherwise
                            if i == numel(names)
                                uialert(app.UIFigure,['The file loaded does not contain a known Neural Network type.'...
                                    ' Please select a different network, or add in the functionality for this network.']);
                            end
                    end
                end
            else
                switch value
                    case 'VitisNet'
                        load(app.AIFiles{1},'net2');
                        app.net = net2;
                    otherwise
                        index = contains(app.NeuralNetDropDown.Items,value);
                        netStruct = load(app.AIFiles{index});
                        names = fieldnames(netStruct);
                        for i = 1:numel(names)
                            switch class(netStruct.(names{i}))
                                case 'SeriesNetwork'
                                    app.net = netStruct.(names{i});
                                    break
                                case 'DAGNetwork'
                                    app.net = netStruct.(names{i});
                                    break
                                otherwise
                                    if i == numel(names)
                                        uialert(app.UIFigure,['The file loaded does not contain a known Neural Network type.'...
                                            ' Please select a different network, or add in the functionality for this network.']);
                                    end
                            end
                        end
                end
            end
            catch err
                uialert(app.UIFigure,err.message,err.identifier)                
            end
        end

        % Button pushed function: AnalyzeFolderButton
        function AnalyzeFolderButtonPushed(app, event)
            
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Gathering the files.');
            files = struct2table(dir(app.Folder));
            files = files(~files.isdir,:);
            files = files(contains(files.name,'.tif','IgnoreCase',true),:);
            newSortData =[];
            app.UITable.Data.StartTime(:) = NaT(height(files),1);
            app.UITable.Data.EndTime(:) = NaT(height(files),1);
            app.UITable.Data.Duration(:) = repmat(duration(0,0,0),height(files),1);
            for i = 1:height(files)
                if mean(app.UITable.Data.Duration)<duration(0,0,1)
                    timeRemain = 'Calculating';
                else
                    timeRemain = mean(app.UITable.Data.Duration(app.UITable.Data.Duration~=0))*(height(files)-i+1);
                end
                d.Value = floor(i-1)/height(files);
                d.Message = sprintf('Analyzing image %d out of %d.\nEstimated time remaining: %s.',i,height(files),timeRemain);
                app.UITable.Data.StartTime(i) =datetime('now');
                tempData = newProcessImage(app.net,fullfile(files.folder{i},files.name{i}));
                if app.SaveResultImageCheckBox.Value
                    if ~isfolder(fullfile(app.Folder,'Labeled'))
                        mkdir(fullfile(app.Folder,'Labeled'))
                    end
                    labeledImage = plotImageLables(app.Folder,tempData);
                    imwrite(labeledImage,fullfile(app.Folder,'Labeled',['Labeled_',tempData.File{1}]));
                end
                newSortData = [newSortData;tempData];
                %     [str,score] = processImage(net,fullfile(files.folder{i},files.name{i}));
                dataSummary = summarizeData(newSortData);
                app.UITable.Data.NumSubImages(i) = dataSummary.NumSample(i);
                app.UITable.Data.Infection(i) = dataSummary.infectedPercent(i)*100;
                app.UITable.Data.EndTime(i) =datetime('now');
                app.UITable.Data.Duration(i) = app.UITable.Data.EndTime(i)-app.UITable.Data.StartTime(i);
            end
            app.sortData = newSortData;
            delete(d);
            
            answer = uiconfirm(app.UIFigure,'Would you like to save the data now?','Save now?','Icon','question','Options',{'Yes','No'});
            switch answer
                case 'Yes'
                    SaveResultsButtonPushed(app)
                case 'No'
                    
            end
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            value = app.FolderEditField.Value;
            if isfolder(value)
                app.FolderEditField.Value = app.Folder;
                OpenFolder(app);
            else
                uialert(app.UIFigure,'That is not a valid path, please select a new path.','Invalid Path');
                app.FolderEditField.Value = event.PreviousValue;
            end
            enableFollowUp(app);
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            msg = 'Would you like to save sub-image data?';
            title = 'Save Sub-Image Data?';
            selection = uiconfirm(app.UIFigure,msg,title,...
                'Options',{'Yes','No','Cancel'},...
                'DefaultOption',2,'CancelOption',3);
            switch selection
                case 'Yes'
                    sheetIndex = contains(app.AIName,app.NeuralNetDropDown.Value);
                    name = app.AIName{sheetIndex};
                    if length(name)>31
                        name = name(1:31);
                    end
                    try
                        writetable(app.sortData,fullfile(app.Folder,'SubImage.xlsx'),'sheet',name);
                    catch err
                        uialert(app.UIFigure,'There was an error writing to SubImage.xlsx. Please make sure the file is closed and try again.','Error Saving Summary');
                    end
                case 'No'
                    
                case 'Cancel'
                    return
                otherwise
            end
            msg = 'Would you like to save the summary data?';
            title = 'Save Summary Data?';
            selection = uiconfirm(app.UIFigure,msg,title,...
                'Options',{'Yes','No','Cancel'},...
                'DefaultOption',2,'CancelOption',3);
            switch selection
                case 'Yes'
                    sheetIndex = contains(app.AIName,app.NeuralNetDropDown.Value);
                    name = app.AIName{sheetIndex};
                    if length(name)>31
                        name = name(1:31);
                    end
                    summaryTable = app.UITable.Data;
                    thresh = [];
                    if numel(app.ThresholdsListBox.Value)>1||~any(strcmp(app.ThresholdsListBox.Value,'0.5'))
                        thresh = app.ThresholdsListBox.Value;
                        thresh = thresh(~strcmp(app.ThresholdsListBox.Value,'0.5'));
                        startIndex = width(summaryTable);
                        summaryTable(:,end+1:end+numel(thresh)) = {0};
                        for i = 1:numel(thresh)
                            dataSummary = summarizeData(app.sortData,str2double(thresh{i}));
                            summaryTable(:,startIndex+i) = dataSummary(:,5);
                            
                        end
                    end
                    try
                        writetable(summaryTable,fullfile(app.Folder,'Summary.xlsx'),'sheet',name);
                        for i = 1:numel(thresh)
                            xlswrite(fullfile(app.Folder,'Summary.xlsx'),{sprintf('Num < %s',thresh{i})},name,sprintf('%s1',char('F'+i)));
                        end
                    catch err
                        uialert(app.UIFigure,'There was an error writing to Summary.xlsx. Please make sure the file is closed and try again.','Error Saving Summary');
                    end
                case 'No'
                    return
                case 'Cancel'
                    return
                otherwise
            end
        end

        % Value changed function: AddThresholdEditField
        function AddThresholdEditFieldValueChanged(app, event)
            value = app.AddThresholdEditField.Value;
            if ~any(strcmp(app.ThresholdsListBox.Items,num2str(value))) && value>0 && value < 1
                items = app.ThresholdsListBox.Items;
                items = [items,{num2str(value)}];
                items = sort(items);
                app.ThresholdsListBox.Items = items;
            end
        end

        % Value changed function: SaveResultImageCheckBox
        function SaveResultImageCheckBoxValueChanged(app, event)
            value = app.SaveResultImageCheckBox.Value;
            if value == true
                responce = uiconfirm(app.UIFigure,sprintf('Are you sure? This will take a lot more time, and consume a lot of storage space.'),'Save Result Images',...
                    'Options',{'Yes','No'},'DefaultOption',2);
                if ~strcmpi(responce,'Yes')
                    app.SaveResultImageCheckBox.Value = false;
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.OpenFolderMenu = uimenu(app.Menu);
            app.OpenFolderMenu.Accelerator = 'o';
            app.OpenFolderMenu.Text = 'Open Folder';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image Name'; 'Infection %'; 'Num Sub Images'; 'StartTime'; 'EndTime'; 'Duration'};
            app.UITable.RowName = {};
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.NeuralNetDropDownLabel = uilabel(app.UIFigure);
            app.NeuralNetDropDownLabel.Text = 'Neural Net';
            app.NeuralNetDropDown = uidropdown(app.UIFigure);
            app.NeuralNetDropDown.Items = {'VitisNet', 'Browse...'};
            app.NeuralNetDropDown.ValueChangedFcn = createCallbackFcn(app, @NeuralNetDropDownValueChanged, true);
            app.NeuralNetDropDown.Value = 'VitisNet';
            app.AnalyzeFolderButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeFolderButtonPushed, true);
            app.AnalyzeFolderButton.Enable = 'off';
            app.AnalyzeFolderButton.Text = 'Analyze Folder';
            app.SaveResultsButton = uibutton(app.UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Enable = 'off';
            app.SaveResultsButton.Text = 'Save Results';
            app.SaveResultImageCheckBox = uicheckbox(app.UIFigure);
            app.SaveResultImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @SaveResultImageCheckBoxValueChanged, true);
            app.SaveResultImageCheckBox.Text = 'Save Result Image';
            app.ThresholdsListBoxLabel = uilabel(app.UIFigure);
            app.ThresholdsListBoxLabel.Text = 'Thresholds';
            app.ThresholdsListBox = uilistbox(app.UIFigure);
            app.ThresholdsListBox.Items = {'0.5', '0.75', '0.9'};
            app.ThresholdsListBox.Multiselect = 'on';
            app.ThresholdsListBox.Value = {'0.5'};
            app.AddThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.AddThresholdEditFieldLabel.Text = 'Add Threshold';
            app.AddThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.AddThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @AddThresholdEditFieldValueChanged, true);
            app.MultiplethresholdscanbeselectedbyholdingCTRLwhenclickingLabel = uilabel(app.UIFigure);
            app.MultiplethresholdscanbeselectedbyholdingCTRLwhenclickingLabel.Text = {'Multiple thresholds can be selected'; 'by holding CTRL when clicking. '};
            app.ThresholdsareonlyappliedwhensavingtheSummaryxlsxfileLabel = uilabel(app.UIFigure);
            app.ThresholdsareonlyappliedwhensavingtheSummaryxlsxfileLabel.Text = {'Thresholds are only applied when'; 'saving the Summary.xlsx file '};
        end
    end

    methods (Access = public)

        % Construct app
        function app = AINetProcessor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end