classdef OptionSetting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        IncludeSubfoldersLabel     matlab.ui.control.Label
        IncludeSubfoldersDropDown  matlab.ui.control.DropDown
        ImageTypeDropDownLabel     matlab.ui.control.Label
        ImageTypeDropDown          matlab.ui.control.DropDown
    end


    properties (Access = private)
        MainApp  % Description
        options % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, options)
            app.MainApp = mainApp;
            app.options  = options; 
            if isempty(app.options)
                app.options = struct('subFolder','No',...
                    'ImageType','.TIF');
            end
            app.IncludeSubfoldersDropDown.Value = app.options.subFolder;
            app.ImageTypeDropDown.Value = app.options.ImageType;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
           SetOptions(app.MainApp,app.options); 
            delete(app)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.IncludeSubfoldersLabel = uilabel(app.UIFigure);
            app.IncludeSubfoldersLabel.Text = 'Include Subfolders?';
            app.IncludeSubfoldersDropDown = uidropdown(app.UIFigure);
            app.IncludeSubfoldersDropDown.Items = {'No', 'Yes'};
            app.IncludeSubfoldersDropDown.Value = 'No';
            app.ImageTypeDropDownLabel = uilabel(app.UIFigure);
            app.ImageTypeDropDownLabel.Text = 'Image Type';
            app.ImageTypeDropDown = uidropdown(app.UIFigure);
            app.ImageTypeDropDown.Items = {'.TIF', '.jpg', '.png'};
            app.ImageTypeDropDown.Editable = 'on';
            app.ImageTypeDropDown.Value = '.TIF';
        end
    end

    methods (Access = public)

        % Construct app
        function app = OptionSetting(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end