classdef randomSelctorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FilesMenu              matlab.ui.container.Menu
        OpenFolderMenu         matlab.ui.container.Menu
        SaveDataMenu           matlab.ui.container.Menu
        ViewTableMenu          matlab.ui.container.Menu
        FolderEditFieldLabel   matlab.ui.control.Label
        FolderEditField        matlab.ui.control.EditField
        SubImagePlot           matlab.ui.control.UIAxes
        GoBack1SubImageButton  matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        NoneButton             matlab.ui.control.Button
        InfectionButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        Folder % Folder where the images are stored.
        h
        CurImage
        CurSubImage
        index = reshape(1:36*24,[36,24]);
        sizeX
        sizeY
        sizeZ
        subSizeX = 224;
        subSizeY = 224;
        setup % Description
        ImageLoaded % Description
        net % Description
        rect % Description
    end
    
    properties (Access = public)
        options % Description
    end
    
    methods (Access = private)
        
        function enableFollowUp(app)
            if isempty(app.UITable.Data)
                %                 app.ModerateButton.Enable = 'off';
                app.NoneButton.Enable = 'off';
                app.InfectionButton.Enable = 'off';
                %                 app.HighButton.Enable = 'off';
                app.GoBack1SubImageButton.Enable = 'off';
                return
            end
            if isfolder(app.Folder)
                first = find(app.UITable.Data.Expert1 == 'Uncategorized',1,'first');
                if isempty(first)
                    %                     app.ModerateButton.Enable = 'off';
                    app.NoneButton.Enable = 'off';
                    app.InfectionButton.Enable = 'off';
                    %                     app.HighButton.Enable = 'off';
                    app.GoBack1SubImageButton.Enable = 'off';
                    return
                end
                if (app.UITable.Data.Expert1(first) == 'Uncategorized' )&& first == 1
                    %                     app.ModerateButton.Enable = 'on';
                    app.NoneButton.Enable = 'on';
                    app.InfectionButton.Enable = 'on';
                    %                     app.HighButton.Enable = 'on';
                    app.GoBack1SubImageButton.Enable = 'off';
                    
                    app.SaveDataMenu.Enable = 'on';
                else
                    %                     app.ModerateButton.Enable = 'on';
                    app.NoneButton.Enable = 'on';
                    app.InfectionButton.Enable = 'on';
                    %                     app.HighButton.Enable = 'on';
                    app.GoBack1SubImageButton.Enable = 'on';
                    
                    app.SaveDataMenu.Enable = 'on';
                end
            end
        end
        
        function results = OpenFolder(app)
            if strcmpi(app.options.subFolder,'No')
                files = struct2table(dir(fullfile(app.Folder)));
            else
                files = struct2table(dir(fullfile(app.Folder,'**')));
            end
            files.name = string(files.name);
            files = files(files.isdir==false,:);
            files = files(contains(files.name,app.options.ImageType,'IgnoreCase',true),:);
            matFile =  struct2table(dir(fullfile(app.Folder,'*.mat')));
            
            if ~isempty(matFile) && (height(matFile)==1) % The folder was sorted to create training data
                s = load(fullfile(matFile.folder,matFile.name));
                if isfield(s,'sortData')
                    sortData = s.sortData;
                else
                    selection = uiconfirm(app.UIFigure,'This folder has not been arranged for this experiment yet, would you like to do so now?','Setup Folder',...
                        'Options',{'Yes','No'},...
                        'DefaultOption',1,'CancelOption',2);
                    switch selection
                        case 'Yes'
                            fileSetup(app);
                            return;
                        case 'No'
                            uialert(app.UIFigure,'This experiment requires a setup period before starting. Please select a folder to initialize the experiement');
                            return
                        otherwise
                    end
                end
                
            end
            if ~isempty(matFile) && (height(matFile)==1) % this is the prefered case.
                s = load(fullfile(matFile.folder,matFile.name));
                if isfield(s,'sortData')
                    sortData = s.sortData;
                else
                    uialert(app.UIFigure,'There was an error','Error')
                    error('there was no sortData table in the .mat file');
                end
                % TODO Test that the folder has not been altered sence last run.
            elseif height(matFile)>1
                uialert(app.UIFigure,'There is more than one .mat file in this folder. Please remove the extra file(s) and try selecting the folder again.','Too many files');
                app.FolderEditField.Value = 'Open a folder from the menu, ctrl+O, or pasting the location here.';
                return
            else
                selection = uiconfirm(app.UIFigure,'This folder has not been arranged for this experiment yet, would you like to do so now?','Setup Folder',...
                    'Options',{'Yes','No'},...
                    'DefaultOption',1,'CancelOption',2);
                switch selection
                    case 'Yes'
                        fileSetup(app);
                        return
                    case 'No'
                        return
                    otherwise
                end
            end
            app.UITable.Data = sortData;
            LoadNextImage(app);
            enableFollowUp(app);
        end
        
        function LoadNextImage(app)
            steps = 4;
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Testing if last image');
            first = find(app.UITable.Data.Expert1 == 'Uncategorized',1,'first');
            app.SubImagePlot.Title.String = sprintf('Image %d out of %d',first,height(app.UITable.Data));
            if isempty(first)
                uialert(app.UIFigure,'That is the end of the list if images, please save the data, either throught the meue or pressing ctrl+s','End of list');
                delete(d);
                return
            end
            row= app.UITable.Data.row(first);
            col= app.UITable.Data.col(first);
            if isempty(app.h)||~ishandle(app.h)
                figure;
                app.h = axes;
            end
            if isempty(app.ImageLoaded)||~strcmpi(app.ImageLoaded,app.UITable.Data.ImageName{first})
                d.Value = 1/steps;
                d.Message = 'Reading in the full image';
                app.CurImage = imread(fullfile(app.Folder,app.UITable.Data.ImageName{first}));
                app.ImageLoaded = app.UITable.Data.ImageName{first};
                
            end
            imshow(app.CurImage,'parent',app.h);
            [app.sizeX,app.sizeY,app.sizeZ] = size(app.CurImage);
            
            d.Value = 2/steps;
            d.Message = 'Cropping out the Sub Image';
            rectPosBbox = [((col-1)*app.subSizeY)+1,((row-1)*app.subSizeX)+1,app.subSizeY-1,app.subSizeX-1];
            if ~isempty(app.rect)
                delete(app.rect)
                app.rect =rectangle(app.h,'Position', rectPosBbox,'edgeColor','r','LineWidth',2);
            else
                app.rect =rectangle(app.h,'Position', rectPosBbox,'edgeColor','r','LineWidth',2);
            end
            app.CurSubImage = imcrop(app.CurImage,rectPosBbox);
            d.Value = 3/steps;
            d.Message = 'Testing for valid sample';
            if ~isSampleMask(app.CurSubImage)
                delete(d);
                uialert(app.UIFigure,'The SubImage is not a vaild sample.','Sub Image Error');
            end
            % test if the image contains a sample
            % if not move untill a sutable image is found that is not already in the set
            d.Value = 4/steps;
            d.Message = 'Plotting Image';
            imshow(app.CurSubImage, 'parent',app.SubImagePlot);
        end
        
        function sortData = fileSetup(app)
            num = 100;
            if strcmpi(app.options.subFolder,'No')
                files = struct2table(dir(fullfile(app.Folder)));
            else
                files = struct2table(dir(fullfile(app.Folder,'**')));
            end
            files.name = string(files.name);
            files = files(files.isdir==false,:);
            files = files(contains(files.name,app.options.ImageType,'IgnoreCase',true),:);
            load('CNN_CombinedTS_3_11Jun2018.mat','net2');
            fullTable = table;
            for i = 1:numel(files.name)
                rand100 = randperm(100)';
                path = files.name{i};
                [str,~] = processImage(net2, fullfile(app.Folder,path));
                outTable{i}= convertImageTable(path,str);
                outTable{i} = outTable{i}(randperm(height(outTable{i})),:);
                NoneLocs = find(outTable{i}.Category == 'None',50);
                NoneTable = outTable{i}(NoneLocs,:);
                infectLocs = find(outTable{i}.Category == 'Infection',100-height(NoneTable));
                infectTable = outTable{i}(infectLocs,:);
                if height(infectTable)+height(NoneTable) == 100
                    outTable{i} = [NoneTable;infectTable];
                else
                    % There is not enough infected locations make up the remaining with None
                    NoneLocs = find(outTable{i}.Category == 'None',100-height(infectTable));
                    NoneTable = outTable{i}(NoneLocs,:);
                    outTable{i} = [NoneTable;infectTable];
                end
                fullTable = [fullTable;outTable{i}(rand100,:)];
            end
            category = categorical(repelem({'Uncategorized'},height(fullTable))',{'Uncategorized','NotASample','OutOfFocus','None','Low','Moderate','High'},{'Uncategorized','NotASample','OutOfFocus','None','Low','Moderate','High'},'Ordinal',true);
            sortData = table(fullTable.File,category,category,fullTable.row,fullTable.col,true(height(fullTable),1),'VariableNames',regexprep(app.UITable.ColumnName,' ',''));
            app.UITable.Data = sortData;
            if isfile(fullfile(app.Folder,'Data.mat'))
                save(fullfile(app.Folder,'Data.mat'),'sortData','-append');
            else
                save(fullfile(app.Folder,'Data.mat'),'sortData');
            end
            LoadNextImage(app);
        end
        
    end
    
    methods (Access = public)
        
        function SetOptions(app,optionsIn)
            app.options = optionsIn;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(app.options)
                app.options = struct('subFolder','No',...
                    'ImageType','.TIF');
            end
            load('CNN_CombinedTS_3_11Jun2018.mat','net2');
            app.net = net2;
        end

        % Menu selected function: OpenFolderMenu
        function OpenFolderMenuSelected(app, event)
            app.Folder = uigetdir('','Select the folder containing your data');
            app.FolderEditField.Value = app.Folder;
            OpenFolder(app);
            enableFollowUp(app);
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            value = app.FolderEditField.Value;
            if isfolder(value)
                app.Folder= value;
                OpenFolder(app);
            else
                app.FolderEditField.Value = 'That folder does not exist. Please select a folder containing your data.';
            end
            enableFollowUp(app);
        end

        % Callback function
        function OptionsMenuSelected(app, event)
            OptionSetting(app,app.options);
        end

        % Button pushed function: InfectionButton, NoneButton
        function SubmitButtonPushed(app, event)
            steps = 4;
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Saving Input');
            choice = event.Source.Text;
            first = find(app.UITable.Data.Expert1 == 'Uncategorized',1,'first');
            app.UITable.Data.Expert1(first) = choice;
            %% TODO set with AI Responce here
            d.Value = 2/steps;
            d.Message = 'Loading Network';
            
            d.Value = 3/steps;
            d.Message = 'Classifying Image';
            aiChoice = classify(app.net,im2uint8(app.CurSubImage));
            if any(contains(categories(app.UITable.Data.Expert2),'Infection'))
                
            end
            app.UITable.Data.Expert2(first) = char(aiChoice);
            d.Value = 4/steps;
            d.Message = 'Comparing Answers';
            app.UITable.Data.E1vE2= app.UITable.Data.Expert1 ==app.UITable.Data.Expert2;
            delete(d);
            LoadNextImage(app);
            enableFollowUp(app);
        end

        % Button pushed function: GoBack1SubImageButton
        function GoBack1SubImageButtonPushed(app, event)
            first = find(app.UITable.Data.Expert1 == 'Uncategorized',1,'first');
            if isempty(first)
                app.UITable.Data.Expert1(end) = 'Uncategorized';
                app.UITable.Data.Expert2(end) = 'Uncategorized';
            else
                app.UITable.Data.Expert1(first-1) = 'Uncategorized';
                app.UITable.Data.Expert2(first-1) = 'Uncategorized';
            end
            LoadNextImage(app);
            enableFollowUp(app);
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            sortData = app.UITable.Data;
            save(fullfile(app.Folder,'Data.mat'),'sortData');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if ~isempty(app.h) && ishandle(app.h)
                    close(app.h.Parent);
                end
            catch
                delete(app);
                error('there was an error closing the program');
            end
            delete(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            disp(event);
            ind = event.Indices(1);
            selection = uiconfirm(app.UIFigure,'Would you like to reset the entire image''s classification, or just the last image?','Reset the image?',...
                'Options',{'Entire Image','Last Sub Image','Nevermind'},...
                'DefaultOption',1,'CancelOption',3);
            switch selection
                case 'Entire Image'
                    app.UITable.Data.Compleated(ind) = false;
                    app.UITable.Data.SubImgCode(ind) = "";
                case 'Last Sub Image'
                    app.UITable.Data.Compleated(ind) = false;
                    arr = char(app.UITable.Data.SubImgCode(ind));
                    app.UITable.Data.SubImgCode(ind) = string(arr(1:end-1));
                case 'Nevermind'
                    app.UITable.Data.Compleated(ind) = true;
                otherwise
                    
            end
            LoadNextImage(app);
            enableFollowUp(app);
        end

        % Menu selected function: ViewTableMenu
        function ViewTableMenuSelected(app, event)
            switch app.UITable.Visible
                case 'off'
                    app.UITable.Visible = 'on';
                case 'on'
                    app.UITable.Visible = 'off';
                otherwise
                    disp(app.UITable.Visible)
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilesMenu = uimenu(app.UIFigure);
            app.FilesMenu.Text = 'Files';
            app.OpenFolderMenu = uimenu(app.FilesMenu);
            app.OpenFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFolderMenuSelected, true);
            app.OpenFolderMenu.Accelerator = 'o';
            app.OpenFolderMenu.Text = 'Open Folder';
            app.SaveDataMenu = uimenu(app.FilesMenu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Enable = 'off';
            app.SaveDataMenu.Accelerator = 'S';
            app.SaveDataMenu.Text = 'Save Data';
            app.ViewTableMenu = uimenu(app.FilesMenu);
            app.ViewTableMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewTableMenuSelected, true);
            app.ViewTableMenu.Visible = 'off';
            app.ViewTableMenu.Accelerator = 't';
            app.ViewTableMenu.Text = 'View Table';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.FolderEditField.Value = 'Open a folder from the menu, ctrl+O, or pasting the location here. ';
            app.SubImagePlot = uiaxes(app.UIFigure);
            title(app.SubImagePlot, 'Sub Image')
            app.GoBack1SubImageButton = uibutton(app.UIFigure, 'push');
            app.GoBack1SubImageButton.ButtonPushedFcn = createCallbackFcn(app, @GoBack1SubImageButtonPushed, true);
            app.GoBack1SubImageButton.Enable = 'off';
            app.GoBack1SubImageButton.Text = 'Go Back 1 Sub Image';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image Name'; 'Expert1'; 'Expert2'; 'row'; 'col'; 'E1vE2'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.Visible = 'off';
            app.NoneButton = uibutton(app.UIFigure, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.NoneButton.Enable = 'off';
            app.NoneButton.Text = 'None';
            app.InfectionButton = uibutton(app.UIFigure, 'push');
            app.InfectionButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.InfectionButton.Enable = 'off';
            app.InfectionButton.Text = 'Infection';
        end
    end

    methods (Access = public)

        % Construct app
        function app = randomSelctorApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end