classdef randomSelctorAppCopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        FilesMenu              matlab.ui.container.Menu
        OpenFolderMenu         matlab.ui.container.Menu
        SaveDataMenu           matlab.ui.container.Menu
        FolderEditFieldLabel   matlab.ui.control.Label
        FolderEditField        matlab.ui.control.EditField
        SubImagePlot           matlab.ui.control.UIAxes
        HighButton             matlab.ui.control.Button
        GoBack1SubImageButton  matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        ModerateButton         matlab.ui.control.Button
        NoneButton             matlab.ui.control.Button
        LowButton              matlab.ui.control.Button
        OutofFocusButton       matlab.ui.control.Button
    end

    
    properties (Access = private)
        Folder % Folder where the images are stored.
        h
        CurImage
        CurSubImage
        index = reshape(1:36*24,[36,24]);
        indexLabel
        sizeX
        sizeY
        sizeZ
        subSizeX
        subSizeY
        rect % Description
        rectPosX % Description
        rectPosY% Description
    end
    
    properties (Access = public)
        options % Description
    end
    
    methods (Access = private)
        
        function enableFollowUp(app)
            if isempty(app.UITable.Data)
                app.ModerateButton.Enable = 'off';
                app.NoneButton.Enable = 'off';
                app.LowButton.Enable = 'off';
                app.HighButton.Enable = 'off';
                app.GoBack1SubImageButton.Enable = 'off';
                app.OutofFocusButton.Enable = 'off';
                return
            end
            if isfolder(app.Folder)
                first = find(~app.UITable.Data.Compleated,1,'first');
                if isempty(first)
                    app.ModerateButton.Enable = 'off';
                    app.NoneButton.Enable = 'off';
                    app.LowButton.Enable = 'off';
                    app.HighButton.Enable = 'off';
                    app.GoBack1SubImageButton.Enable = 'off';
                    app.OutofFocusButton.Enable = 'off';
                    return
                end
                if isempty(app.UITable.Data.SubImgCode(first)) && first == 1
                    app.ModerateButton.Enable = 'on';
                    app.NoneButton.Enable = 'on';
                    app.LowButton.Enable = 'on';
                    app.HighButton.Enable = 'on';
                    app.OutofFocusButton.Enable = 'on';
                    app.GoBack1SubImageButton.Enable = 'off';
                    
                    app.SaveDataMenu.Enable = 'on';
                elseif (isempty(app.UITable.Data.SubImgCode(first)))
                    app.ModerateButton.Enable = 'on';
                    app.NoneButton.Enable = 'on';
                    app.LowButton.Enable = 'on';
                    app.HighButton.Enable = 'on';
                    app.OutofFocusButton.Enable = 'on';
                    app.GoBack1SubImageButton.Enable = 'off';
                    
                    app.SaveDataMenu.Enable = 'on';
                else
                    app.ModerateButton.Enable = 'on';
                    app.NoneButton.Enable = 'on';
                    app.LowButton.Enable = 'on';
                    app.HighButton.Enable = 'on';
                    app.OutofFocusButton.Enable = 'on';
                    app.GoBack1SubImageButton.Enable = 'on';
                    
                    app.SaveDataMenu.Enable = 'on';
                end
            end
        end
        
        function results = OpenFolder(app)
            if strcmpi(app.options.subFolder,'No')
                files = struct2table(dir(fullfile(app.Folder)));
            else
                files = struct2table(dir(fullfile(app.Folder,'**')));
            end
            files.name = string(files.name);
            folders = files(files.isdir==true,:);
            
            if any(contains(folders.name,"Catagories"))
                
            else
                mkdir(fullfile(app.Folder,'Catagories'));
                mkdir(fullfile(app.Folder,'Catagories','None'));
                mkdir(fullfile(app.Folder,'Catagories','Low'));
                mkdir(fullfile(app.Folder,'Catagories','Moderate'));
                mkdir(fullfile(app.Folder,'Catagories','High'));
                mkdir(fullfile(app.Folder,'Catagories','OutOfFocus'));
            end
            files = files(files.isdir==false,:);
            files = files(contains(files.name,app.options.ImageType,'IgnoreCase',true),:);
            matFile =  struct2table(dir(fullfile(app.Folder,'*.mat')));
            
            if ~isempty(matFile) && (height(matFile)==1)
                load(fullfile(matFile.folder,matFile.name),'tableData');
                tempData = table(files.name,false(height(files),1),false(height(files),1),strings(height(files),1),'VariableNames',regexprep(app.UITable.ColumnName,' ',''));
                
                temptable = [tableData;tempData];
                [~,ia,ic] = unique(temptable.ImageName,'first','rows');
                tempData = temptable(ia,:);
                if height(tableData)>height(tempData)
                    uialert(app.UIFigure,'There are more files in the data file than in the folder, please verify the files.','Too many files requested');
                    app.FolderEditField.value = 'Open a folder from the menu, ctrl+O, or pasting the location here.';
                    return;
                    
                elseif height(tableData)<height(tempData)
                    selection  = uiconfirm(app.UIFigure,'Files have been added to this list, would you like to add the files to this list?','New Files Found',...
                        'Options',{'Yes','No'},...
                        'DefaultOption',1,'CancelOption',2);
                    switch selection
                        case 'Yes'
                            temptable = [tempData;tableData];
                            [~,ia,ic] = unique(temptable.ImageName,'first','rows');
                            tableData = temptable(ia,:);
                        case 'No'
                            selection  = uiconfirm(app.UIFigure,'Would you like to start classifying from the beginning?','Start Over?',...
                                'Options',{'Yes','No'},...
                                'DefaultOption',1,'CancelOption',2);
                            switch selection
                                case 'Yes'
                                    tableData = table(files.name,false(height(files),1),false(height(files),1),strings(height(files),1),'VariableNames',regexprep(app.UITable.ColumnName,' ',''));
                                case 'No'
                                    uialert(app.UIFigure,'Please correct the number of files and retry later.','Retry later');
                                    return
                                otherwise
                            end
                        otherwise
                    end
                    
                end
            elseif height(matFile)>1
                uialert(app.UIFigure,'There is more than one excel file in this folder. Please remove the extra file(s) and try selecting the folder again.','Too many files');
                app.FolderEditField.value = 'Open a folder from the menu, ctrl+O, or pasting the location here.';
            else
                tableData = table(files.name,false(height(files),1),false(height(files),1),strings(height(files),1),'VariableNames',regexprep(app.UITable.ColumnName,' ',''));
            end
            app.UITable.Data = tableData;
            LoadNextImage(app);
            enableFollowUp(app);
        end
        
        function LoadNextImage(app)
            steps = 4;
            d = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Testing if last image');
            first = find(~app.UITable.Data.Compleated,1,'first');
            if strlength(app.UITable.Data.SubImgCode(first))+1>numel(app.index)
                app.UITable.Data.Compleated(first) = true;
                if contains(app.UITable.Data.SubImgCode(first),["2","3","4"])
                    app.UITable.Data.ContainsHyphae(first) = true;
                end
                first = find(~app.UITable.Data.Compleated,1,'first');
            end
            if isempty(first)
                uialert(app.UIFigure,'That is the end of the list if images, please save the data, either throught the meue or pressing ctrl+s','End of list');
                return
            end
            d.Value = 1/steps;
            d.Message = 'Finding next sub-image';
            [row,col,~] = find(app.index==strlength(app.UITable.Data.SubImgCode(first))+1,1,'first');
            app.indexLabel = sprintf('(%d,%d)',col,row);
            if isempty(app.h)||~ishandle(app.h)
                figure;
                app.h = axes;
                app.CurImage = imread(fullfile(app.Folder,app.UITable.Data.ImageName{first}));
            end
            
            if (row == 1) && (col == 1)
                app.CurImage = imread(fullfile(app.Folder,app.UITable.Data.ImageName{first}));
            end
            hold off
            d.Value = 2/steps;
            d.Message = 'Ploting data';
            imshow(app.CurImage, 'parent',app.h);
            [app.sizeX,app.sizeY,app.sizeZ] = size(app.CurImage);
            app.subSizeX = 224;
            app.subSizeY = 224;
            hold on
            [row,col,~] = find(app.index<=strlength(app.UITable.Data.SubImgCode(first)));
            if ~isempty(row)
                p1 = ((row-1)*app.subSizeY)+1;
                p2 = ((col-1)*app.subSizeX)+1;
                p4 = ones(size(p1))*app.subSizeX-1;
                p3 = ones(size(p1))*app.subSizeY-1;
                
                points = bbox2points([p1,p2,p3,p4]);
                points = permute(points,[2,1,3]);
                vert = reshape(points,[2,numel(points)/2])';
                ind = 1:length(vert);
                ind = reshape(ind,[4,length(ind)/4])';
                
                exp = {'0','1','2','3','4','5'};
                rep = {'k','b','g','y','r','c'};
                c = regexprep(char(app.UITable.Data.SubImgCode(first)),exp,rep);
                color = c(:);
                
                patch(app.h,'Faces',ind(color=='k',:),'vertices',vert,'FaceColor','m','FaceAlpha',.3)
                patch(app.h,'Faces',ind(color=='b',:),'vertices',vert,'FaceColor','b','FaceAlpha',.3)
                patch(app.h,'Faces',ind(color=='r',:),'vertices',vert,'FaceColor','r','FaceAlpha',.3)
                patch(app.h,'Faces',ind(color=='g',:),'vertices',vert,'FaceColor','g','FaceAlpha',.3)
                patch(app.h,'Faces',ind(color=='y',:),'vertices',vert,'FaceColor','y','FaceAlpha',.3)
                patch(app.h,'Faces',ind(color=='c',:),'vertices',vert,'FaceColor','c','FaceAlpha',.3)
            end
            
            [row,col,~] = find(app.index==strlength(app.UITable.Data.SubImgCode(first))+1,1,'first');
            rectPosBbox = [((row-1)*app.subSizeY)+1,((col-1)*app.subSizeX)+1,app.subSizeY-1,app.subSizeX-1];
            rectangle(app.h,'Position', rectPosBbox);
            app.CurSubImage = imcrop(app.CurImage,rectPosBbox);
            drawnow;
            d.Value = 3/steps;
            d.Message = 'Testing if valid Sample';
            % test if the image contains a sample
            [isSample, ~ ] = isSampleMask(app.CurSubImage);
            if isSample == false
                event.Source.Text = 'NotASample';
                SubmitButtonPushed(app,event);
            end
            d.Value = 4/steps;
            d.Message = 'Finishing';
            close(d);
            imshow(app.CurSubImage, 'parent',app.SubImagePlot);
            
        end
        
    end
    
    methods (Access = public)
        
        function SetOptions(app,optionsIn)
            app.options = optionsIn;
        end
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isempty(app.options)
                app.options = struct('subFolder','No',...
                    'ImageType','.TIF');
            end
        end

        % Menu selected function: OpenFolderMenu
        function OpenFolderMenuSelected(app, event)
            app.Folder = uigetdir('','Select the folder containing your data');
            app.FolderEditField.Value = app.Folder;
            OpenFolder(app);
            enableFollowUp(app);
        end

        % Value changed function: FolderEditField
        function FolderEditFieldValueChanged(app, event)
            value = app.FolderEditField.Value;
            if isfolder(value)
                app.Folder= value;
                OpenFolder(app);
            else
                app.FolderEditField.Value = 'That folder does not exist. Please select a folder containing your data.';
            end
            enableFollowUp(app);
        end

        % Callback function
        function OptionsMenuSelected(app, event)
            OptionSetting(app,app.options);
        end

        % Button pushed function: HighButton, LowButton, 
        % ModerateButton, NoneButton, OutofFocusButton
        function SubmitButtonPushed(app, event)
            choice = event.Source.Text;
            first = find(~app.UITable.Data.Compleated,1,'first');
            [~,name,ext ] = fileparts(app.UITable.Data.ImageName(first));
            
            switch choice
                case 'NotASample'
                    choice = "0";
                case 'None'
                    choice = "1";
                    imwrite(app.CurSubImage,fullfile(app.Folder,'Catagories','None',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                case 'Low'
                    choice = "2";
                    imwrite(app.CurSubImage,fullfile(app.Folder,'Catagories','Low',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                case 'Moderate'
                    choice = "3";
                    imwrite(app.CurSubImage,fullfile(app.Folder,'Catagories','Moderate',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                case 'High'
                    choice = "4";
                    imwrite(app.CurSubImage,fullfile(app.Folder,'Catagories','High',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                case 'Out of Focus'
                    choice = "5";
                    imwrite(app.CurSubImage,fullfile(app.Folder,'Catagories','OutOfFocus',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                otherwise
                    
            end
            arr = app.UITable.Data.SubImgCode(first);
            arr = strcat(arr,choice);
            app.UITable.Data.SubImgCode(first) = arr;
            LoadNextImage(app);
            enableFollowUp(app);
            if mod(strlength(arr),10) == 0
                SaveDataMenuSelected(app);
            end
        end

        % Button pushed function: GoBack1SubImageButton
        function GoBack1SubImageButtonPushed(app, event)
            first = find(~app.UITable.Data.Compleated,1,'first');
            if isempty(first)
                app.UITable.Data.Compleated(end) = false;
                arr = char(app.UITable.Data.SubImgCode(end));
                
                charArr = char(arr);
                while ~isempty(charArr) && strcmp(charArr(end),'0')
                    charArr = charArr(1:end-1);
                end
                arr = string(charArr);
                [row,col,~] = find(app.index==length(charArr),1,'first');
                app.indexLabel = sprintf('(%d,%d)',col,row);
                [~,name,ext ] = fileparts(app.UITable.Data.ImageName(end));
                switch charArr(end)
                    case '0'
                        
                    case '1'
                        delete(fullfile(app.Folder,'Catagories','None',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '2'
                        delete(fullfile(app.Folder,'Catagories','Low',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '3'
                        delete(fullfile(app.Folder,'Catagories','Moderate',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '4'
                        delete(fullfile(app.Folder,'Catagories','High',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '5'
                        delete(fullfile(app.Folder,'Catagories','OutOfFocus',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    otherwise
                        
                end
                app.UITable.Data.SubImgCode(first) = string(charArr(1:end-1));
            elseif isempty(app.UITable.Data.SubImgCode(first))
                app.UITable.Data.Compleated(first-1) = false;
                arr = char(app.UITable.Data.SubImgCode(first-1));
                
                charArr = char(arr);
                while ~isempty(charArr) && strcmp(charArr(end),'0')
                    charArr = charArr(1:end-1);
                end
                arr = string(charArr);
                [row,col,~] = find(app.index==length(charArr),1,'first');
                app.indexLabel = sprintf('(%d,%d)',col,row);
                [~,name,ext ] = fileparts(app.UITable.Data.ImageName(first-1));
                switch charArr(end)
                    case '0'
                        
                    case '1'
                        delete(fullfile(app.Folder,'Catagories','None',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '2'
                        delete(fullfile(app.Folder,'Catagories','Low',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '3'
                        delete(fullfile(app.Folder,'Catagories','Moderate',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '4'
                        delete(fullfile(app.Folder,'Catagories','High',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '5'
                        delete(fullfile(app.Folder,'Catagories','OutOfFocus',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    otherwise
                        
                end
                app.UITable.Data.SubImgCode(first-1) = string(charArr(1:end-1));
            else
                arr = char(app.UITable.Data.SubImgCode(first));
                
                charArr = char(arr);
                while ~isempty(charArr) && strcmp(charArr(end),'0')
                    charArr = charArr(1:end-1);
                end
                arr = string(charArr);
                [row,col,~] = find(app.index==length(charArr),1,'first');
                app.indexLabel = sprintf('(%d,%d)',col,row);
                [~,name,ext ] = fileparts(app.UITable.Data.ImageName(first));
                switch charArr(end)
                    case '0'
                        
                    case '1'
                        delete(fullfile(app.Folder,'Catagories','None',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '2'
                        delete(fullfile(app.Folder,'Catagories','Low',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '3'
                        delete(fullfile(app.Folder,'Catagories','Moderate',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '4'
                        delete(fullfile(app.Folder,'Catagories','High',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    case '5'
                        delete(fullfile(app.Folder,'Catagories','OutOfFocus',sprintf('%s_%s%s',name,app.indexLabel,ext)));
                    otherwise
                        
                end
                app.UITable.Data.SubImgCode(first) = string(charArr(1:end-1));
            end
            LoadNextImage(app);
            enableFollowUp(app);
        end

        % Menu selected function: SaveDataMenu
        function SaveDataMenuSelected(app, event)
            tableData = app.UITable.Data;
            save(fullfile(app.Folder,'Data.mat'),'tableData');
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                if ~isempty(app.h) && ishandle(app.h)
                    close(app.h.Parent);
                end
            catch
                delete(app);
                error('there was an error closing the program');
            end
            delete(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            disp(event);
            ind = event.Indices(1);
            selection = uiconfirm(app.UIFigure,'Would you like to reset the entire image''s classification, or just the last image?','Reset the image?',...
                'Options',{'Entire Image','Last Sub Image','Nevermind'},...
                'DefaultOption',1,'CancelOption',3);
            switch selection
                case 'Entire Image'
                    app.UITable.Data.Compleated(ind) = false;
                    app.UITable.Data.SubImgCode(ind) = "";
                case 'Last Sub Image'
                    app.UITable.Data.Compleated(ind) = false;
                    arr = char(app.UITable.Data.SubImgCode(ind));
                    app.UITable.Data.SubImgCode(ind) = string(arr(1:end-1));
                case 'Nevermind'
                    app.UITable.Data.Compleated(ind) = true;
                otherwise
                    
            end
            LoadNextImage(app);
            enableFollowUp(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.FilesMenu = uimenu(app.UIFigure);
            app.FilesMenu.Text = 'Files';
            app.OpenFolderMenu = uimenu(app.FilesMenu);
            app.OpenFolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFolderMenuSelected, true);
            app.OpenFolderMenu.Accelerator = 'o';
            app.OpenFolderMenu.Text = 'Open Folder';
            app.SaveDataMenu = uimenu(app.FilesMenu);
            app.SaveDataMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveDataMenuSelected, true);
            app.SaveDataMenu.Enable = 'off';
            app.SaveDataMenu.Accelerator = 'S';
            app.SaveDataMenu.Text = 'Save Data';
            app.FolderEditFieldLabel = uilabel(app.UIFigure);
            app.FolderEditFieldLabel.Text = 'Folder';
            app.FolderEditField = uieditfield(app.UIFigure, 'text');
            app.FolderEditField.ValueChangedFcn = createCallbackFcn(app, @FolderEditFieldValueChanged, true);
            app.FolderEditField.Value = 'Open a folder from the menu, ctrl+O, or pasting the location here. ';
            app.SubImagePlot = uiaxes(app.UIFigure);
            title(app.SubImagePlot, 'Sub Image')
            app.HighButton = uibutton(app.UIFigure, 'push');
            app.HighButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.HighButton.Enable = 'off';
            app.HighButton.Text = 'High';
            app.GoBack1SubImageButton = uibutton(app.UIFigure, 'push');
            app.GoBack1SubImageButton.ButtonPushedFcn = createCallbackFcn(app, @GoBack1SubImageButtonPushed, true);
            app.GoBack1SubImageButton.Enable = 'off';
            app.GoBack1SubImageButton.Text = 'Go Back 1 Sub Image';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Image Name'; 'Compleated'; 'ContainsHyphae'; 'SubImgCode'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.ModerateButton = uibutton(app.UIFigure, 'push');
            app.ModerateButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.ModerateButton.Enable = 'off';
            app.ModerateButton.Text = 'Moderate';
            app.NoneButton = uibutton(app.UIFigure, 'push');
            app.NoneButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.NoneButton.Enable = 'off';
            app.NoneButton.Text = 'None';
            app.LowButton = uibutton(app.UIFigure, 'push');
            app.LowButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.LowButton.Enable = 'off';
            app.LowButton.Text = 'Low';
            app.OutofFocusButton = uibutton(app.UIFigure, 'push');
            app.OutofFocusButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.OutofFocusButton.Enable = 'off';
            app.OutofFocusButton.Text = 'Out of Focus';
        end
    end

    methods (Access = public)

        % Construct app
        function app = randomSelctorAppCopy

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end